classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrainingPanel                   matlab.ui.container.Panel
        SelectManmadesetButton          matlab.ui.control.Button
        SelectNaturalsetButton          matlab.ui.control.Button
        TrainmodelButton                matlab.ui.control.Button
        TestingPanel                    matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SelectTargetimageButton         matlab.ui.control.Button
        GenerateMosaicButton            matlab.ui.control.Button
        NumberofpixelspertileEditFieldLabel  matlab.ui.control.Label
        NumberofpixelspertileEditField  matlab.ui.control.NumericEditField
        NumberoftilestouseEditFieldLabel  matlab.ui.control.Label
        NumberoftilestouseEditField     matlab.ui.control.NumericEditField
        SelectModelButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        SaveMosaicButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        mmcount
        ntcount
        fnameloadmm
        fnameloadnt
        fnameloadsi
        tgstruc
        tgl
        mname
        fname_order
        sourcemf
        tg
        d
        comp
    end
    
    methods (Access = private)
        
        function opclass = testmodel(app,fnameload,mf,Mdl)
            tthist = calhistogram2(app,fnameload,mf);
            
            ttline = countline(app,fnameload,mf);
            
            tthist = [tthist ttline];
            
            opclass = {};
            for i = 1:mf
                class_type = predict(Mdl.Mdl,tthist(i,:));
                opclass(i).fname = fnameload{i};
                opclass(i).class = class_type;
                opclass(i).r = tthist(i,1);
                opclass(i).g = tthist(i,2);
                opclass(i).b = tthist(i,3);
                opclass(i).linenum = tthist(i,4);
                %opclass = [opclass;fnameload{i} class_type{i}];
            end
            
            
        end


        function linenum = countline(app,fnameload,mf)
            
            linenum = [];
            thr = 30;
            sig = 2;
            for i = 1:mf
                h = imread(fnameload{i});
                BW = edge(rgb2gray(h),'canny',thr/255,sig);
                
                
                %%%% Emphasize line%%%%
                %BW = conv2(BW,ones(3),'same');
                %BW(BW<3) = 0;
                %BW(BW>0) = 1;
                
                [BWx BWy] = size(BW);
                [H,T,R] = hough(BW,'RhoResolution',0.5,'Theta',-90:0.35:89);
                P = houghpeaks(H,300,'Threshold',0.3*max(H(:)),'NHoodSize',[3,3]);
                lines = houghlines(BW,T,R,P);
                plotline = 0;
                for k = 1:length(lines)
                    %delete line in edge of piture
                    if(abs(lines(k).point1(1)-lines(k).point2(1)) < 10 && (lines(k).point1(1)< 0.1*BWx || lines(k).point1(1) > 0.9*BWx))
                        continue;
                    end
                    
                    if(abs(lines(k).point1(2)-lines(k).point2(2)) < 10 && (lines(k).point1(2)< 0.1*BWy || lines(k).point1(2) > 0.9*BWy))
                        continue;
                    end
                    
                    c = improfile(BW,[lines(k).point1(1) lines(k).point2(1)],[lines(k).point1(2) lines(k).point2(2)]); %count pixels in line
                    [cm cn] = size(c);
                    [lcm lcn] = size(c(c==1));
                    %delete line that pixel in line < 35%
                    if(lcm < 0.35*cm)
                        continue;
                    end
                    
                    dis = ((lines(k).point1(1)-lines(k).point2(1))^2 + (lines(k).point1(2)-lines(k).point2(2))^2)^0.5;
                    
                    
                    %delete line length < 5% of maximum side of image
                    if(dis < 0.05*max(BWx,BWy))
                        continue;
                    end
                    
                    
                    %delete line that do not have continous segment > 30%
                    if(max(conv(c,ones(round(0.3*cm),1))) < round(0.3*cm)-1)
                        continue;
                    end
                    
                    
                    plotline = plotline +1;
                end
                linenum = [linenum;plotline];
            end
            
        end
        
        function inputhist = calhistogram2(app, fnameload,mf)
            inputhist = [];
            for i = 1:mf
                h = imread(fnameload{i});
                hR = h(:,:,1);
                hG = h(:,:,2);
                hB = h(:,:,3);
                hR = hR./255; %% normalisation
                hG = hG./255; %% normalisation
                hB = hB./255; %% normalisation
                inputhist = [inputhist;mean(mean(hR)) mean(mean(hG)) mean(mean(hB))];
            end
        end
        
        function fname = loadimage(app, str_title)
            %load in folder and sub folders
            start_path = fullfile(matlabroot, 'ImgComp\');
            topLevelFolder = uigetdir(start_path,str_title);
            allSubFolders = genpath(topLevelFolder);
            remain = allSubFolders;
            listOfFolderNames = {};
            while true
                [singleSubFolder, remain] = strtok(remain, ';');
                if isempty(singleSubFolder)
                    break;
                end
                listOfFolderNames = [listOfFolderNames singleSubFolder];
            end
            numberOfFolders = length(listOfFolderNames);
            fname = {};
            %iterate through all folder and search for all .jpg files
            for k=1: numberOfFolders
                thisFolder = listOfFolderNames{k};
                filePattern = sprintf('%s/*.jpg', thisFolder);
                baseFileNames = dir(filePattern);
                numberOfTrainingFiles = length(baseFileNames);
                
                for f=1 : numberOfTrainingFiles
                    fullFileName = fullfile(thisFolder, baseFileNames(f).name);
                    fname{f} = fullFileName;
                end
            end
        end
        
        
        function tgname = loadtargetimage(app)
            [file,path] = uigetfile('*.jpg','Select Target Image');
            if isequal(file,0)
                
            else
                tgname = fullfile(path,file);
                %disp(['User selected ', fullfile(path,file)]);
            end
        end
        
        function mname = loadtargetmodel(app)
            [file,path] = uigetfile('*.mat','Select Model');
            if isequal(file,0)
                
            else
                mname = fullfile(path,file);
                %disp(['User selected ', fullfile(path,file)]);
            end
        end
        
        function img = crop(app, np, im)
            %%% Gaussian smooting and resize twice
            [m,n,l] = size(im);
            
            img = imgaussfilt(im,5);
            img = imresize(img,np*2/min(m,n));
            %img = imgaussfilt(img,5);
            img = imresize(img,0.5);
            [m,n,l] = size(img);
            if m<n
                img = imcrop(img,[round((n-m)/2) 1 app.NumberofpixelspertileEditField.Value-1 app.NumberofpixelspertileEditField.Value-1]);
            elseif m>n
                img = imcrop(img,[1 round((m-n)/2) app.NumberofpixelspertileEditField.Value-1 app.NumberofpixelspertileEditField.Value-1]);
            end
        end


        function diff = calcs(app,lab1,lab2)
            if isequal(size(lab1), size(lab2))
                diff = sqrt(sum((lab1 - lab2) .^ 2));
                diff = mean(reshape(diff(:, :, :), 1, []));
                %diff = mean(diff);
            else
                diff = 999999;
            end
        end
        
        function comp = composite_hard(app,tg,fname)
            %%%Combine composite image by compute similarity of chi-square distance
            %%%tg = Target Image
            %%%fname = List of file name of tiles image
            
            [tgrp,tgcp,tgl] = size(tg); % tgrp,tgcp : Number of pixel in Row and Column in target image
            
            [fl,fn] = size(fname);                      %Size of file name
            
            nrt = ceil(tgrp/app.NumberofpixelspertileEditField.Value);                        % Number of tiles in row
            nct = ceil(tgcp/app.NumberofpixelspertileEditField.Value);                        % Number of tiles in column
            
            lab1 = [];
            for i = 1:fn
                tiles(i).img = crop(app, app.NumberofpixelspertileEditField.Value, imread(fname{i}));
                %%% Calculating chi-square distance between img and tgf
                Red = tiles(i).img(:,:,1);
                Green = tiles(i).img(:,:,2);
                Blue = tiles(i).img(:,:,3);
                %Get histValues for each channel
                hRed = imhist(Red);
                hGreen = imhist(Green);
                hBlue = imhist(Blue);
                hRed = hRed./255; %% normalisation
                hGreen = hGreen./255; %% normalisation
                hBlue = hBlue./255; %% normalisation
                %lab1(:,:,i) = [hRed, hGreen, hBlue];
                temp = rgb2lab(tiles(i).img);
                if isequal(size(temp), size(lab1(:,:,:,1))) || isempty(lab1) 
                    lab1(:,:,:,i) = temp;
                else
                    temp(end,:,:) = [];
                    temp(:,end,:) = [];
                    lab1(:,:,:,i) = temp;
                end      
            end
            
            comp = [];
            n = 1;                                      %Index of tiles
            
            dupck = [];                                 %Duplicate image check
            
            for i = 1:nct
                rimage = [];                            %Row images
                for j = 1:nct
                    
                    %if i == nrt || j == nct
                        %%% Crop target image to find the distance
                        %%% At the edge of target image, extend target image
                        %tge = [tg imcrop(tg,[1 1 app.NumberofpixelspertileEditField.Value-1 tgcp-1]);
                            %imcrop(tg,[1 1 tgcp-1 app.NumberofpixelspertileEditField.Value-1]) imcrop(tg,[1 1 app.NumberofpixelspertileEditField.Value-1 app.NumberofpixelspertileEditField.Value-1])];
                        %tgc = imcrop(tge,[1+(i-1)*app.NumberofpixelspertileEditField.Value 1+(j-1)*app.NumberofpixelspertileEditField.Value app.NumberofpixelspertileEditField.Value-1 app.NumberofpixelspertileEditField.Value-1]);
                    %else
                        x=i*app.NumberofpixelspertileEditField.Value;
                        y=j*app.NumberofpixelspertileEditField.Value;
                        tgc = imcrop(tg,[x y app.NumberofpixelspertileEditField.Value-1 app.NumberofpixelspertileEditField.Value-1]);
                    %end
                    if y>tgrp || isa(tgc, 'double')
                        break;
                    end
                    lab2 = rgb2lab(tgc);
                    tgRed = tgc(:,:,1);
                    tgGreen = tgc(:,:,2);
                    tgBlue = tgc(:,:,3);
                    %Get histValues for each channel
                    htgRed = imhist(tgRed);
                    htgGreen= imhist(tgGreen);
                    htgBlue = imhist(tgBlue);
                    htgRed = htgRed./255; %% normalisation
                    htgGreen = htgGreen./255; %% normalisation
                    htgBlue = htgBlue./255; %% normalisation
                    tgh = [htgRed, htgGreen, htgBlue];
                    simimagearray= [,];
                    for k = 1:fn                        %Find suitable image from less chi-square distance
                        simimage = calcs(app, lab1(:,:,:,k), lab2);
                        simimagearray= [simimagearray; simimage, k];
                    end
                    [temp, order] = sort(simimagearray(:,1));
                    simimagearray = simimagearray(order,:);
                    suitableimage = simimagearray(1,2);
                    
                    dupck = [dupck suitableimage];
                    
                    rimage = [rimage, tiles(suitableimage).img]; %Combine row image
                    n = n + 1;
                end
                rimage = permute(rimage,[2,1,3]);
                [compsizex compsizey]= size(comp);
                [rimagesizex rimagesizey] = size(rimage);
                if compsizex ==rimagesizex || compsizex == 0
                    comp = [comp rimage];                   %Combine column image
                else
                    disp('stop')
                end
            end
            
            comp = imcrop(comp,[1 1 tgcp-1 tgrp-1]);     %Crop composite image to the same size with target image
            %hsv = rgb2hsv(temp);
            %hsv(:,:,2) = hsv(:,:,2)*1.2;
            %comp = hsv2rgb(hsv);
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: SelectManmadesetButton
        function SelectManmadesetButtonPushed(app, event)
            app.fnameloadmm = loadimage(app, 'Select folder of training manmade');                %Select folder of source images
            [nf mf] = size(app.fnameloadmm);
            app.mmcount = mf;
            
        end

        % Button pushed function: SelectNaturalsetButton
        function SelectNaturalsetButtonPushed(app, event)
            app.fnameloadnt = loadimage(app, 'Select folder of training natural');                %Select folder of source images
            [nf mf] = size(app.fnameloadnt);
            app.ntcount = mf;
        end

        % Button pushed function: TrainmodelButton
        function TrainmodelButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Extracting features');
            pause(.5)
            mmhist = calhistogram2(app,app.fnameloadmm,app.mmcount);
            mmline = countline(app, app.fnameloadmm,app.mmcount);
            nthist = calhistogram2(app,app.fnameloadnt,app.ntcount);
            ntline = countline(app,app.fnameloadnt,app.ntcount);
            d.Message = 'Training model';
            pause(.5)
            X = [mmhist mmline;nthist ntline];
            Y={};
            for i = 1:app.mmcount
                Y{i} = 'MM';
            end
            
            for i = app.mmcount+1:app.mmcount+app.ntcount
                Y{i} = 'NT';
            end
            
            
            Mdl = fitcknn(X,Y,'NumNeighbors',5,'Standardize',1);
            close(d);
            save('lastmodel.mat', 'Mdl');
        end

        % Button pushed function: SelectTargetimageButton
        function SelectTargetimageButtonPushed(app, event)
            app.d = pwd;
            tgname = loadtargetimage(app); %Full path of target image
            app.tg = imread(tgname); % Target Image
            app.tgstruc = {tgname};
            imshow(app.tg, 'Parent', app.UIAxes2);
        end

        % Button pushed function: SelectModelButton
        function SelectModelButtonPushed(app, event)
            model = loadtargetmodel(app);
            app.mname = load(model);
        end

        % Button pushed function: GenerateMosaicButton
        function GenerateMosaicButtonPushed(app, event)
            l = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Testing target');
            assignin('base','pixels',app.NumberofpixelspertileEditField.Value);
            tgclass = testmodel(app, app.tgstruc,1,app.mname); % tgclass.class = 'MM' for Manmade and  'NT' for Natural
            t1 = tgclass.class;
            t2 = t1{1};
            cd(app.d);
            l.Message= 'Selecting source images';
            pause(.5)
            if t2 == "NT"
                addpath Natural;
                cd Natural;
                thisFolder = pwd;
                cd ..;
                filePattern = sprintf('%s/*.jpg', thisFolder);
                baseFileNames = dir(filePattern);
                numberOfTrainingFiles = length(baseFileNames);
                for f=1 : numberOfTrainingFiles
                    fullFileName = fullfile(thisFolder, baseFileNames(f).name);
                    app.fnameloadsi{f} = fullFileName;
                end
            else
                addpath Manmade;
                cd Manmade;
                thisFolder = pwd;
                cd ..;
                filePattern = sprintf('%s/*.jpg', thisFolder);
                baseFileNames = dir(filePattern);
                numberOfTrainingFiles = length(baseFileNames);
                for f=1 : numberOfTrainingFiles
                    fullFileName = fullfile(thisFolder, baseFileNames(f).name);
                    app.fnameloadsi{f} = fullFileName;
                end
            end
            l.Message = 'Processing the data';
            pause(.5)
            [nf app.sourcemf] = size(app.fnameloadsi);
            app.fname_order = randperm(app.sourcemf);             %Random source image
            pause(.5)
            i = 1;
            n = 1;
            fname = {};
            while true
                if i == app.sourcemf+1
                    break;
                end
                %assignin('base','fnameloadsi',app.fnameloadsi)
                %assignin('base','i',i)
                %sistruc = {app.fnameloadsi{i}}; % Source image structure
                %siclass = testmodel(app, sistruc, 1, app.mname); % siclass.class = 'MM' for Manmade and  'NT' for Natural
                %s1 = siclass.class;
                %s2 = s1{1};
                
                %if t2 == s2
                fname{n} = app.fnameloadsi{app.fname_order(i)};
                n = n+1;
                i = i+1;
                if n>app.NumberoftilestouseEditField.Value
                    break;
                end
                continue;
                %end
                %i = i+1;
            end
            l.Message = "Creating composite";
            pause(.5)
            %%%%% Create Composite Image %%%%%
            app.comp = composite_hard(app,app.tg,fname);
            imshow(uint8(app.comp), 'Parent', app.UIAxes);
            close(l);
        end

        % Callback function
        function SelectSourcefolderButtonPushed(app, event)
            
        end

        % Value changed function: NumberofpixelspertileEditField
        function NumberofpixelspertileEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: SaveMosaicButton
        function SaveMosaicButtonPushed(app, event)
            comp=app.comp;
            imwrite(comp, 'Mosaic.png')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrainingPanel = uipanel(app.UIFigure);
            app.TrainingPanel.Title = 'Training';
            app.SelectManmadesetButton = uibutton(app.TrainingPanel, 'push');
            app.SelectManmadesetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectManmadesetButtonPushed, true);
            app.SelectManmadesetButton.Text = 'Select Manmade set';
            app.SelectNaturalsetButton = uibutton(app.TrainingPanel, 'push');
            app.SelectNaturalsetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNaturalsetButtonPushed, true);
            app.SelectNaturalsetButton.Text = 'Select Natural set';
            app.TrainmodelButton = uibutton(app.TrainingPanel, 'push');
            app.TrainmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainmodelButtonPushed, true);
            app.TrainmodelButton.Text = 'Train model';
            app.TestingPanel = uipanel(app.UIFigure);
            app.TestingPanel.Title = 'Testing';
            app.UIAxes = uiaxes(app.TestingPanel);
            title(app.UIAxes, 'Mosaic')
            app.SelectTargetimageButton = uibutton(app.TestingPanel, 'push');
            app.SelectTargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetimageButtonPushed, true);
            app.SelectTargetimageButton.Text = 'Select Target image';
            app.GenerateMosaicButton = uibutton(app.TestingPanel, 'push');
            app.GenerateMosaicButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMosaicButtonPushed, true);
            app.GenerateMosaicButton.Text = 'Generate Mosaic';
            app.NumberofpixelspertileEditFieldLabel = uilabel(app.TestingPanel);
            app.NumberofpixelspertileEditFieldLabel.Text = 'Number of pixels per tile';
            app.NumberofpixelspertileEditField = uieditfield(app.TestingPanel, 'numeric');
            app.NumberofpixelspertileEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpixelspertileEditFieldValueChanged, true);
            app.NumberoftilestouseEditFieldLabel = uilabel(app.TestingPanel);
            app.NumberoftilestouseEditFieldLabel.Text = 'Number of tiles to use';
            app.NumberoftilestouseEditField = uieditfield(app.TestingPanel, 'numeric');
            app.SelectModelButton = uibutton(app.TestingPanel, 'push');
            app.SelectModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButtonPushed, true);
            app.SelectModelButton.Text = 'Select Model';
            app.UIAxes2 = uiaxes(app.TestingPanel);
            title(app.UIAxes2, 'Target image')
            app.SaveMosaicButton = uibutton(app.TestingPanel, 'push');
            app.SaveMosaicButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMosaicButtonPushed, true);
            app.SaveMosaicButton.Text = 'Save Mosaic';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end